classdef nose_poke_simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RPokeLamp                matlab.ui.control.Lamp
        RPokeLampLabel           matlab.ui.control.Label
        LPokeLamp                matlab.ui.control.Lamp
        LPokeLampLabel           matlab.ui.control.Label
        RightPokeHolePanel       matlab.ui.container.Panel
        LeftPokeHolePanel        matlab.ui.container.Panel
        RightPokeCueLamp         matlab.ui.control.Lamp
        RightPokeLightLampLabel  matlab.ui.control.Label
        LeftPokeCueLamp          matlab.ui.control.Lamp
        LeftPokeLightLampLabel   matlab.ui.control.Label
        StimIndicatorLamp        matlab.ui.control.Lamp
        StimIndicatorLampLabel   matlab.ui.control.Label
        BeepLightSyncLamp        matlab.ui.control.Lamp
        BeepLightSyncLampLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        LeftHolePoke % 0 when no nosepoke, 1 while beam is interupted
        RightHolePoke  % same as LeftHolePoke for right hole
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % turn all lights to off color, dark gray
            app.LPokeLamp.Color = [.5 .5 .5];
            app.RPokeLamp.Color = [.5 .5 .5];
            app.RightPokeCueLamp.Color = [.5 .5 .5];
            app.LeftPokeCueLamp.Color = [.5 .5 .5];
            app.StimIndicatorLamp.Color = [.5 .5 .5];
            app.BeepLightSyncLamp.Color = [.5 .5 .5];

            % set our nose-poke state external variables to non-poke state
            app.LeftHolePoke = 0;
            app.RightHolePoke = 0;

        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
             clickpt = app.UIFigure.CurrentPoint;  % this is a two-element array (x,y)
           
             %disp(['click ' num2str(clickpt)])
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.LeftHolePoke = 0;
            app.RightHolePoke = 0;
            app.LPokeLamp.Color = [.5 .5 .5];
            app.RPokeLamp.Color = [.5 .5 .5];
            %disp(['UP click'])

        end

        % Button down function: LeftPokeHolePanel
        function LeftPokeHolePanelButtonDown(app, event)

            app.LeftHolePoke = 1;
            app.LPokeLamp.Color = [0 1 0];
        end

        % Button down function: RightPokeHolePanel
        function RightPokeHolePanelButtonDown(app, event)
            app.RightHolePoke = 1;
            app.RPokeLamp.Color = [0 1 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.BeepLightSyncLampLabel = uilabel(app.UIFigure);
            app.BeepLightSyncLampLabel.Text = 'Beep & Light Sync';
            app.BeepLightSyncLamp = uilamp(app.UIFigure);
            app.StimIndicatorLampLabel = uilabel(app.UIFigure);
            app.StimIndicatorLampLabel.Text = 'Stim Indicator';
            app.StimIndicatorLamp = uilamp(app.UIFigure);
            app.LeftPokeLightLampLabel = uilabel(app.UIFigure);
            app.LeftPokeLightLampLabel.Text = 'Left Poke Light';
            app.LeftPokeCueLamp = uilamp(app.UIFigure);
            app.RightPokeLightLampLabel = uilabel(app.UIFigure);
            app.RightPokeLightLampLabel.Text = 'Right Poke Light';
            app.RightPokeCueLamp = uilamp(app.UIFigure);
            app.LeftPokeHolePanel = uipanel(app.UIFigure);
            app.LeftPokeHolePanel.Title = 'Left Poke Hole';
            app.LeftPokeHolePanel.ButtonDownFcn = createCallbackFcn(app, @LeftPokeHolePanelButtonDown, true);
            app.RightPokeHolePanel = uipanel(app.UIFigure);
            app.RightPokeHolePanel.Title = 'Right Poke Hole';
            app.RightPokeHolePanel.ButtonDownFcn = createCallbackFcn(app, @RightPokeHolePanelButtonDown, true);
            app.LPokeLampLabel = uilabel(app.UIFigure);
            app.LPokeLampLabel.Text = 'L Poke';
            app.LPokeLamp = uilamp(app.UIFigure);
            app.RPokeLampLabel = uilabel(app.UIFigure);
            app.RPokeLampLabel.Text = 'R Poke';
            app.RPokeLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nose_poke_simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end